classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure       matlab.ui.Figure
        kpSlider       matlab.ui.control.Slider
        kpSliderLabel  matlab.ui.control.Label
        TsSlider       matlab.ui.control.Slider
        TsSliderLabel  matlab.ui.control.Label
        UIAxes2        matlab.ui.control.UIAxes
        UIAxes         matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: kpSlider
        function KpSliderValueChanged(app, event)
            Kp_value = app.kpSlider.Value;
            Ts = app.TsSlider.Value;
            
            numerator = [10.06];
            denominator = [1, 2.646, 6.462, 5.49];

            sys_s = tf(numerator, denominator);
            sys_z_c2d = c2d(sys_s, Ts, 'zoh'); % 'zoh' stands for zero-order hold

            step(app.UIAxes,sys_z_c2d)
            zgrid(app.UIAxes2,'on')
            pzmap(app.UIAxes2, sys_z_c2d)

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.TsSliderLabel = uilabel(app.UIFigure);
            app.TsSliderLabel.Text = 'Ts';
            app.TsSlider = uislider(app.UIFigure);
            app.TsSlider.Limits = [0 0.5];
            app.kpSliderLabel = uilabel(app.UIFigure);
            app.kpSliderLabel.Text = 'kp';
            app.kpSlider = uislider(app.UIFigure);
            app.kpSlider.Limits = [0 20];
            app.kpSlider.ValueChangedFcn = createCallbackFcn(app, @KpSliderValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end